classdef CreateDictionary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        OutputFolderEditFieldLabel      matlab.ui.control.Label
        OutputFolderEditField           matlab.ui.control.EditField
        NumberofFeaturesperFrameEditFieldLabel  matlab.ui.control.Label
        NumberofFeaturesperFrameEditField  matlab.ui.control.EditField
        FeatureQualityEditFieldLabel    matlab.ui.control.Label
        FeatureQualityEditField         matlab.ui.control.EditField
        NumberofFeatureOctavesEditFieldLabel  matlab.ui.control.Label
        NumberofFeatureOctavesEditField  matlab.ui.control.EditField
        CamerasTab                      matlab.ui.container.Tab
        CameraDictionary1EditFieldLabel  matlab.ui.control.Label
        CameraDictionary1EditField      matlab.ui.control.EditField
        CameraParametersEditFieldLabel  matlab.ui.control.Label
        CameraParametersEditField       matlab.ui.control.EditField
        ResolutionofLogitechc270DropDownLabel  matlab.ui.control.Label
        ResolutionofLogitechc270DropDown  matlab.ui.control.DropDown
        ResolutionKeyEditFieldLabel     matlab.ui.control.Label
        ResolutionKeyEditField          matlab.ui.control.EditField
        UseCustomCameraSwitchLabel      matlab.ui.control.Label
        UseCustomCameraSwitch           matlab.ui.control.Switch
        RunButton                       matlab.ui.control.StateButton
        CompileButton                   matlab.ui.control.StateButton
        SnapButton                      matlab.ui.control.StateButton
        SaveObjectButton                matlab.ui.control.StateButton
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = public)
        plotHandles;
        userConfig;
        params;
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            % Setup
            setup();
            
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            
            if app.RunButton.Value
                
                % Set buttons
                app.CompileButton.Value = 0;
            
                % Load user config
                app.userConfig.outputFolder = app.OutputFolderEditField.Value;
                app.userConfig.cameraDict = str2num(app.CameraDictionary1EditField.Value); 
                app.userConfig.resolutionKey = app.ResolutionKeyEditField.Value;
                app.userConfig.cameraParameters = app.CameraParametersEditField.Value;
                app.userConfig.nFeatures = str2double(app.NumberofFeaturesperFrameEditField.Value);
                app.userConfig.surfThreshold = str2double(app.FeatureQualityEditField.Value);
                app.userConfig.nOctaves = str2double(app.NumberofFeatureOctavesEditField.Value);
              
                % Load params
                if strcmp(app.UseCustomCameraSwitch.Value, 'Off')
                    cameraParameterDict.('RGB24_640x480') = {'cameraParameters1_RGB24_640x480.mat'}; % Drop down dict
                    cameraParameterDict.('RGB24_1280x720') = {'cameraParameters1_RGB24_1280x720.mat'};
                    cameraParameters = cameraParameterDict.(app.ResolutionofLogitechc270DropDown.Value);
                    cameraParameters = cameraParameters{1};
                    app.userConfig.resolutionKey = app.ResolutionofLogitechc270DropDown.Value;
                else
                    cameraParameters = app.userConfig.cameraParameters;
                end
                v = load(cameraParameters);
                app.params.cameraParameters = v.cameraParameters1; % Change if different var name than file name
                
                % Output folder
                mkdir(app.userConfig.outputFolder);
                
                % Run
                results = recordObjects(app);
                objectDict = results; % Rename
                save(sprintf('%s/objectDict.mat', app.userConfig.outputFolder), 'objectDict');
                   
            else
                disp('Already running')
                app.RunButton.Value = 1;     
            end
            
        end

        % Value changed function: CompileButton
        function CompileButtonValueChanged(app, event)
            
            if app.CompileButton.Value
                disp('Terminating')
                app.RunButton.Value = 0;
            else
                disp('Already terminating')
                app.CompileButton.Value = 1;
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.OutputFolderEditFieldLabel = uilabel(app.GeneralTab);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.GeneralTab, 'text');
            app.OutputFolderEditField.Value = 'results';
            app.NumberofFeaturesperFrameEditFieldLabel = uilabel(app.GeneralTab);
            app.NumberofFeaturesperFrameEditFieldLabel.Text = 'Number of Features per Frame';
            app.NumberofFeaturesperFrameEditField = uieditfield(app.GeneralTab, 'text');
            app.NumberofFeaturesperFrameEditField.Value = '100';
            app.FeatureQualityEditFieldLabel = uilabel(app.GeneralTab);
            app.FeatureQualityEditFieldLabel.Text = 'Feature Quality';
            app.FeatureQualityEditField = uieditfield(app.GeneralTab, 'text');
            app.FeatureQualityEditField.Value = '1000';
            app.NumberofFeatureOctavesEditFieldLabel = uilabel(app.GeneralTab);
            app.NumberofFeatureOctavesEditFieldLabel.Text = 'Number of Feature Octaves';
            app.NumberofFeatureOctavesEditField = uieditfield(app.GeneralTab, 'text');
            app.NumberofFeatureOctavesEditField.Value = '8';
            app.CamerasTab = uitab(app.TabGroup);
            app.CamerasTab.Title = 'Cameras';
            app.CameraDictionary1EditFieldLabel = uilabel(app.CamerasTab);
            app.CameraDictionary1EditFieldLabel.Text = 'Camera Dictionary (1)';
            app.CameraDictionary1EditField = uieditfield(app.CamerasTab, 'text');
            app.CameraDictionary1EditField.Value = '1';
            app.CameraParametersEditFieldLabel = uilabel(app.CamerasTab);
            app.CameraParametersEditFieldLabel.Text = 'Camera Parameters';
            app.CameraParametersEditField = uieditfield(app.CamerasTab, 'text');
            app.CameraParametersEditField.Value = 'cameraParameters1.mat';
            app.ResolutionofLogitechc270DropDownLabel = uilabel(app.CamerasTab);
            app.ResolutionofLogitechc270DropDownLabel.Text = 'Resolution of Logitech c270 ';
            app.ResolutionofLogitechc270DropDown = uidropdown(app.CamerasTab);
            app.ResolutionofLogitechc270DropDown.Items = {'RGB24_640x480', 'RGB24_1280x720'};
            app.ResolutionofLogitechc270DropDown.Value = 'RGB24_640x480';
            app.ResolutionKeyEditFieldLabel = uilabel(app.CamerasTab);
            app.ResolutionKeyEditFieldLabel.Text = 'Resolution Key';
            app.ResolutionKeyEditField = uieditfield(app.CamerasTab, 'text');
            app.ResolutionKeyEditField.Value = 'RGB24_640x480';
            app.UseCustomCameraSwitchLabel = uilabel(app.CamerasTab);
            app.UseCustomCameraSwitchLabel.Text = 'Use Custom Camera';
            app.UseCustomCameraSwitch = uiswitch(app.CamerasTab, 'slider');
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.CompileButton = uibutton(app.UIFigure, 'state');
            app.CompileButton.ValueChangedFcn = createCallbackFcn(app, @CompileButtonValueChanged, true);
            app.CompileButton.Text = 'Compile';
            app.SnapButton = uibutton(app.UIFigure, 'state');
            app.SnapButton.Text = 'Snap';
            app.SaveObjectButton = uibutton(app.UIFigure, 'state');
            app.SaveObjectButton.Text = 'Save Object';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Create Dictionary - Version 0 - 20/01/2020 -  Master Thesis ''A Pose Estimation and Object Tracking Framework for Behavior Studies using Camera Fusion'' - Bachmann David, Wolf Julian, Hess Stephan';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CreateDictionary

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end