classdef PrintMarkers < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        OutputFolderEditFieldLabel     matlab.ui.control.Label
        OutputFolderEditField          matlab.ui.control.EditField
        MarkerSizemEditField_2Label    matlab.ui.control.Label
        MarkerSizemEditField           matlab.ui.control.EditField
        NumberofMarkersEditFieldLabel  matlab.ui.control.Label
        NumberofMarkersEditField       matlab.ui.control.EditField
        RunButton                      matlab.ui.control.StateButton
        Label                          matlab.ui.control.Label
    end

    
    properties (Access = public)
        plotHandles;
        userConfig;
        params;
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            % Setup
            setup();
            
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            
            if app.RunButton.Value
            
                % Load user config
                app.userConfig.outputFolder = app.OutputFolderEditField.Value;
                app.userConfig.nMarkers = str2double(app.NumberofMarkersEditField.Value);
                app.userConfig.markerSize = str2double(app.MarkerSizemEditField.Value);
                
                % Output folder
                mkdir(app.userConfig.outputFolder);
                
                % Run
                printMarkers(app);
                
                % Reset button
                app.RunButton.Value = 0;
                
            else
                disp('Already running')
                app.RunButton.Value = 1;     
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.OutputFolderEditFieldLabel = uilabel(app.GeneralTab);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.GeneralTab, 'text');
            app.OutputFolderEditField.Value = 'results';
            app.MarkerSizemEditField_2Label = uilabel(app.GeneralTab);
            app.MarkerSizemEditField_2Label.Text = 'Marker Size [m]';
            app.MarkerSizemEditField = uieditfield(app.GeneralTab, 'text');
            app.MarkerSizemEditField.Value = '0.05';
            app.NumberofMarkersEditFieldLabel = uilabel(app.GeneralTab);
            app.NumberofMarkersEditFieldLabel.Text = 'Number of Markers';
            app.NumberofMarkersEditField = uieditfield(app.GeneralTab, 'text');
            app.NumberofMarkersEditField.Value = '10';
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Print Markers - Version 0 - 20/01/2020 -  Master Thesis ''A Pose Estimation and Object Tracking Framework for Behavior Studies using Camera Fusion'' - Bachmann David, Wolf Julian, Hess Stephan';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PrintMarkers

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end