classdef RecordFrames < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        GeneralTab                    matlab.ui.container.Tab
        OutputFolderEditFieldLabel    matlab.ui.control.Label
        OutputFolderEditField         matlab.ui.control.EditField
        NumberofFramesEditFieldLabel  matlab.ui.control.Label
        NumberofFramesEditField       matlab.ui.control.EditField
        CamerasTab                    matlab.ui.container.Tab
        CameraDictionary3EditFieldLabel  matlab.ui.control.Label
        CameraDictionary3EditField    matlab.ui.control.EditField
        ResolutionofLogitechc270DropDownLabel  matlab.ui.control.Label
        ResolutionofLogitechc270DropDown  matlab.ui.control.DropDown
        ResolutionKeyEditFieldLabel   matlab.ui.control.Label
        ResolutionKeyEditField        matlab.ui.control.EditField
        UseCustomCameraSwitchLabel    matlab.ui.control.Label
        UseCustomCameraSwitch         matlab.ui.control.Switch
        RunButton                     matlab.ui.control.StateButton
        Label                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        plotHandles;
        userConfig;
        params;
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            % Setup
            setup();
            
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            
            if app.RunButton.Value
                
                % Load user config
                app.userConfig.outputFolder = app.OutputFolderEditField.Value;
                app.userConfig.cameraDict = str2num(app.CameraDictionary3EditField.Value); 
                app.userConfig.resolutionKey = app.ResolutionKeyEditField.Value;
                app.userConfig.nFrames = str2double(app.NumberofFramesEditField.Value);
                
                % Custom resolution key
                if strcmp(app.UseCustomCameraSwitch.Value, 'Off')
                    app.userConfig.resolutionKey = app.ResolutionofLogitechc270DropDown.Value;
                end
                
                % Output folder
                mkdir(app.userConfig.outputFolder);
                
                % Run
                recordFrames(app); % No results, directly to directory
                
                % Reset button
                app.RunButton.Value = 0;
                
            else
                disp('Already running')
                app.RunButton.Value = 1;     
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.OutputFolderEditFieldLabel = uilabel(app.GeneralTab);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.GeneralTab, 'text');
            app.OutputFolderEditField.Value = 'results';
            app.NumberofFramesEditFieldLabel = uilabel(app.GeneralTab);
            app.NumberofFramesEditFieldLabel.Text = 'Number of Frames';
            app.NumberofFramesEditField = uieditfield(app.GeneralTab, 'text');
            app.NumberofFramesEditField.Value = '100';
            app.CamerasTab = uitab(app.TabGroup);
            app.CamerasTab.Title = 'Cameras';
            app.CameraDictionary3EditFieldLabel = uilabel(app.CamerasTab);
            app.CameraDictionary3EditFieldLabel.Text = 'Camera Dictionary (3)';
            app.CameraDictionary3EditField = uieditfield(app.CamerasTab, 'text');
            app.CameraDictionary3EditField.Value = '[1, 2, 3]';
            app.ResolutionofLogitechc270DropDownLabel = uilabel(app.CamerasTab);
            app.ResolutionofLogitechc270DropDownLabel.Text = 'Resolution of Logitech c270 ';
            app.ResolutionofLogitechc270DropDown = uidropdown(app.CamerasTab);
            app.ResolutionofLogitechc270DropDown.Items = {'RGB24_640x480', 'RGB24_1280x720'};
            app.ResolutionofLogitechc270DropDown.Value = 'RGB24_640x480';
            app.ResolutionKeyEditFieldLabel = uilabel(app.CamerasTab);
            app.ResolutionKeyEditFieldLabel.Text = 'Resolution Key';
            app.ResolutionKeyEditField = uieditfield(app.CamerasTab, 'text');
            app.ResolutionKeyEditField.Value = 'RGB24_640x480';
            app.UseCustomCameraSwitchLabel = uilabel(app.CamerasTab);
            app.UseCustomCameraSwitchLabel.Text = 'Use Custom Camera';
            app.UseCustomCameraSwitch = uiswitch(app.CamerasTab, 'slider');
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Record Frames - Version 0 - 20/01/2020 -  Master Thesis ''A Pose Estimation and Object Tracking Framework for Behavior Studies using Camera Fusion'' - Bachmann David, Wolf Julian, Hess Stephan';
        end
    end

    methods (Access = public)

        % Construct app
        function app = RecordFrames

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end