classdef RunFusion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        OutputFolderEditFieldLabel      matlab.ui.control.Label
        OutputFolderEditField           matlab.ui.control.EditField
        TrajectorytoRestoreEditFieldLabel  matlab.ui.control.Label
        TrajectorytoRestoreEditField    matlab.ui.control.EditField
        RestoreSwitchLabel              matlab.ui.control.Label
        RestoreSwitch                   matlab.ui.control.Switch
        CamerasTab                      matlab.ui.container.Tab
        CameraDictionary3EditFieldLabel  matlab.ui.control.Label
        CameraDictionary3EditField      matlab.ui.control.EditField
        CameraParameters1EditFieldLabel  matlab.ui.control.Label
        CameraParameters1EditField      matlab.ui.control.EditField
        CameraParameters2EditFieldLabel  matlab.ui.control.Label
        CameraParameters2EditField      matlab.ui.control.EditField
        CameraParameters3EditFieldLabel  matlab.ui.control.Label
        CameraParameters3EditField      matlab.ui.control.EditField
        ResolutionofLogitechc270DropDownLabel  matlab.ui.control.Label
        ResolutionofLogitechc270DropDown  matlab.ui.control.DropDown
        ResolutionKeyEditFieldLabel     matlab.ui.control.Label
        ResolutionKeyEditField          matlab.ui.control.EditField
        UseCustomCameraSwitchLabel      matlab.ui.control.Label
        UseCustomCameraSwitch           matlab.ui.control.Switch
        PoseEstimationTab               matlab.ui.container.Tab
        MarkerDictionaryfirstentryistheoriginEditFieldLabel  matlab.ui.control.Label
        MarkerDictionaryfirstentryistheoriginEditField  matlab.ui.control.EditField
        MarkerSizemEditFieldLabel       matlab.ui.control.Label
        MarkerSizemEditField            matlab.ui.control.EditField
        UsePoseEstimationSwitchLabel    matlab.ui.control.Label
        UsePoseEstimationSwitch         matlab.ui.control.Switch
        ProbabilityThresholdforMarkerMatchingEditFieldLabel  matlab.ui.control.Label
        ProbabilityThresholdforMarkerMatchingEditField  matlab.ui.control.EditField
        ObjectDetectionTab              matlab.ui.container.Tab
        NumberofobjectinstanceswrtobjectIDsEditFieldLabel  matlab.ui.control.Label
        NumberofobjectinstanceswrtobjectIDsEditField  matlab.ui.control.EditField
        MinimumdistancebetweenobjectspxEditFieldLabel  matlab.ui.control.Label
        MinimumdistancebetweenobjectspxEditField  matlab.ui.control.EditField
        MinimumobjectsizeforROIestimationmEditFieldLabel  matlab.ui.control.Label
        MinimumobjectsizeforROIestimationmEditField  matlab.ui.control.EditField
        ObjectdictionaryEditFieldLabel  matlab.ui.control.Label
        ObjectdictionaryEditField       matlab.ui.control.EditField
        UseObjectTrackingSwitchLabel    matlab.ui.control.Label
        UseObjectTrackingSwitch         matlab.ui.control.Switch
        ProbabilityThresholdforObjectMatchingEditFieldLabel  matlab.ui.control.Label
        ProbabilityThresholdforObjectMatchingEditField  matlab.ui.control.EditField
        FixedVarianceforMatchingmEditFieldLabel  matlab.ui.control.Label
        FixedVarianceforMatchingmEditField  matlab.ui.control.EditField
        FeatureQualityEditFieldLabel    matlab.ui.control.Label
        FeatureQualityEditField         matlab.ui.control.EditField
        NumberofFeatureOctavesEditFieldLabel  matlab.ui.control.Label
        NumberofFeatureOctavesEditField  matlab.ui.control.EditField
        RatioforambiguousMatchesEditFieldLabel  matlab.ui.control.Label
        RatioforambiguousMatchesEditField  matlab.ui.control.EditField
        FilterTab                       matlab.ui.container.Tab
        Processvarianceforlinearmotionofcamerams2EditFieldLabel  matlab.ui.control.Label
        Processvarianceforlinearmotionofcamerams2EditField  matlab.ui.control.EditField
        Processvarianceforangularmotionofcamerarads2EditFieldLabel  matlab.ui.control.Label
        Processvarianceforangularmotionofcamerarads2EditField  matlab.ui.control.EditField
        Processvarianceforlinearmotionofobjectms2EditFieldLabel  matlab.ui.control.Label
        Processvarianceforlinearmotionofobjectms2EditField  matlab.ui.control.EditField
        MeasurementvarianceforpositionofstereocamerasmEditFieldLabel  matlab.ui.control.Label
        MeasurementvarianceforpositionofstereocamerasmEditField  matlab.ui.control.EditField
        MeasurementvariancefororientationofcameraradEditFieldLabel  matlab.ui.control.Label
        MeasurementvariancefororientationofstereocamerasradEditField  matlab.ui.control.EditField
        MeasurementvarianceforpositionofobjectmEditFieldLabel  matlab.ui.control.Label
        MeasurementvarianceforpositionofobjectmEditField  matlab.ui.control.EditField
        MeasurementvarianceforpositionofcameramEditField_2Label  matlab.ui.control.Label
        MeasurementvarianceforpositionofcameramEditField  matlab.ui.control.EditField
        MeasurementvariancefororientationofcameraradEditField_2Label  matlab.ui.control.Label
        MeasurementvariancefororientationofcameraradEditField  matlab.ui.control.EditField
        RunButton                       matlab.ui.control.StateButton
        StopButton                      matlab.ui.control.StateButton
        FreezeMapButton                 matlab.ui.control.StateButton
        FreezeStereoPoseButton          matlab.ui.control.StateButton
        Label                           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = public)
        plotHandles;
        userConfig;
        params;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            % Setup
            setup();
            
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            
            if app.RunButton.Value
                
                % Set buttons
                app.StopButton.Value = 0;
            
                % Load user config
                app.userConfig.outputFolder = app.OutputFolderEditField.Value;
                app.userConfig.restoreFlag = strcmp(app.RestoreSwitch.Value, 'On');
                app.userConfig.cameraDict = str2num(app.CameraDictionary3EditField.Value); 
                app.userConfig.resolutionKey = app.ResolutionKeyEditField.Value;
                app.userConfig.cameraParameters1 = app.CameraParameters1EditField.Value;
                app.userConfig.cameraParameters2 = app.CameraParameters2EditField.Value;
                app.userConfig.cameraParameters3 = app.CameraParameters3EditField.Value;
                app.userConfig.markerDict = str2num(app.MarkerDictionaryfirstentryistheoriginEditField.Value);
                app.userConfig.markerSize = str2double(app.MarkerSizemEditField.Value);
                app.userConfig.pThreshMarker = str2double(app.ProbabilityThresholdforMarkerMatchingEditField.Value);
                app.userConfig.surfThreshold = str2double(app.FeatureQualityEditField.Value);
                app.userConfig.nOctaves = str2double(app.NumberofFeatureOctavesEditField.Value);
                app.userConfig.ambigRatio = str2double(app.RatioforambiguousMatchesEditField.Value);
                app.userConfig.nInstances = str2num(app.NumberofobjectinstanceswrtobjectIDsEditField.Value);
                app.userConfig.minDistance = str2double(app.MinimumdistancebetweenobjectspxEditField.Value);
                app.userConfig.minObjectSize = str2double(app.MinimumobjectsizeforROIestimationmEditField.Value);
                app.userConfig.objectMatchingL2Weight = str2double(app.FixedVarianceforMatchingmEditField.Value);
                app.userConfig.pThreshObject = str2double(app.ProbabilityThresholdforObjectMatchingEditField.Value);
                app.userConfig.objectDict = app.ObjectdictionaryEditField.Value;
                app.userConfig.qCameraLin = str2double(app.Processvarianceforlinearmotionofcamerams2EditField.Value);
                app.userConfig.qCameraAng = str2double(app.Processvarianceforangularmotionofcamerarads2EditField.Value);
                app.userConfig.qObjectLin = str2double(app.Processvarianceforlinearmotionofobjectms2EditField.Value);
                app.userConfig.rStereoCamerasPos = str2double(app.MeasurementvarianceforpositionofstereocamerasmEditField.Value);
                app.userConfig.rStereoCamerasOrient = str2double(app.MeasurementvariancefororientationofstereocamerasradEditField.Value);
                app.userConfig.rCameraPos = str2double(app.MeasurementvarianceforpositionofcameramEditField.Value);
                app.userConfig.rCameraOrient = str2double(app.MeasurementvariancefororientationofcameraradEditField.Value);
                app.userConfig.rObjectPos = str2double(app.MeasurementvarianceforpositionofobjectmEditField.Value);
          
                % Load params
                if strcmp(app.UseCustomCameraSwitch.Value, 'Off')
                    cameraParameterDict.('RGB24_640x480') = {'cameraParameters1_RGB24_640x480.mat', 'cameraParameters2_RGB24_640x480.mat', 'cameraParameters3_RGB24_640x480.mat'}; % Drop down dict
                    cameraParameterDict.('RGB24_1280x720') = {'cameraParameters1_RGB24_1280x720.mat', 'cameraParameters2_RGB24_1280x720.mat', 'cameraParameters3_RGB24_1280x720.mat'};
                    cameraParameters = cameraParameterDict.(app.ResolutionofLogitechc270DropDown.Value);
                    cameraParameters1 = cameraParameters{1};
                    cameraParameters2 = cameraParameters{2};
                    cameraParameters3 = cameraParameters{3};
                    app.userConfig.resolutionKey = app.ResolutionofLogitechc270DropDown.Value;
                else
                    cameraParameters1 = app.userConfig.cameraParameters1;
                    cameraParameters2 = app.userConfig.cameraParameters2;
                    cameraParameters3 = app.userConfig.cameraParameters3;
                end
                v1 = load(cameraParameters1);
                v2 = load(cameraParameters2);
                v3 = load(cameraParameters3);
                v4 = load(app.userConfig.objectDict);
                app.params.cameraParameters1 = v1.cameraParameters1; % Change if different var name than file name
                app.params.cameraParameters2 = v2.cameraParameters2;
                app.params.cameraParameters3 = v3.cameraParameters3;
                app.params.objectDict = v4.objectDict;
                if app.userConfig.restoreFlag
                    v = load(app.TrajectorytoRestoreEditField.Value);
                    app.params.trajectory = v.trajectory;
                end
                
                % Checks
                if ~(numel(app.userConfig.cameraDict) == 3); error('3 Cameras are required in the dictionary'); end
                if ~(numel(app.userConfig.nInstances) == max(vertcat(app.params.objectDict.ObjectId{:}), [], 'all')); error('Not enought entries in the instance array to match the object dictionary, define an array entry for each object in the dictionary'); end
                
                
                % Output folder
                mkdir(app.userConfig.outputFolder);
                
                % Run
                if strcmp(app.UsePoseEstimationSwitch.Value, 'On') && strcmp(app.UseObjectTrackingSwitch.Value, 'On')
                    results = fusion(app);
                elseif strcmp(app.UsePoseEstimationSwitch.Value, 'On') && strcmp(app.UseObjectTrackingSwitch.Value, 'Off')
                    results = poseEstimation(app);
                elseif strcmp(app.UsePoseEstimationSwitch.Value, 'Off') && strcmp(app.UseObjectTrackingSwitch.Value, 'On')
                    results = objectTracking(app);
                else
                    error('No toolbox components are enabled')
                end
                trajectory = results; % Rename
                save(sprintf('%s/trajectory.mat', app.userConfig.outputFolder), 'trajectory');
                
            else
                disp('Already running')
                app.RunButton.Value = 1;     
            end
            
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            
            if app.StopButton.Value
                disp('Terminating')
                app.RunButton.Value = 0;
            else
                disp('Already terminating')
                app.StopButton.Value = 1;
            end
            
        end

        % Value changed function: FreezeMapButton
        function FreezeMapButtonValueChanged(app, event)
            if strcmp(app.UsePoseEstimationSwitch.Value, 'Off')
                disp('Pose estimation is off, freezing the map has no effect')
            end
        end

        % Value changed function: FreezeStereoPoseButton
        function FreezeStereoPoseButtonValueChanged(app, event)
            if strcmp(app.UseObjectTrackingSwitch.Value, 'Off')
                disp('Object tracking is off, freezing the stereo pose has no effect')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.OutputFolderEditFieldLabel = uilabel(app.GeneralTab);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.GeneralTab, 'text');
            app.OutputFolderEditField.Value = 'temp_fusion';
            app.TrajectorytoRestoreEditFieldLabel = uilabel(app.GeneralTab);
            app.TrajectorytoRestoreEditFieldLabel.Text = 'Trajectory to Restore';
            app.TrajectorytoRestoreEditField = uieditfield(app.GeneralTab, 'text');
            app.TrajectorytoRestoreEditField.Value = 'trajectory.mat';
            app.RestoreSwitchLabel = uilabel(app.GeneralTab);
            app.RestoreSwitchLabel.Text = 'Restore';
            app.RestoreSwitch = uiswitch(app.GeneralTab, 'slider');
            app.CamerasTab = uitab(app.TabGroup);
            app.CamerasTab.Title = 'Cameras';
            app.CameraDictionary3EditFieldLabel = uilabel(app.CamerasTab);
            app.CameraDictionary3EditFieldLabel.Text = 'Camera Dictionary (3)';
            app.CameraDictionary3EditField = uieditfield(app.CamerasTab, 'text');
            app.CameraDictionary3EditField.Value = '[3, 1, 2]';
            app.CameraParameters1EditFieldLabel = uilabel(app.CamerasTab);
            app.CameraParameters1EditFieldLabel.Text = 'Camera Parameters 1';
            app.CameraParameters1EditField = uieditfield(app.CamerasTab, 'text');
            app.CameraParameters1EditField.Value = 'cameraParameters1.mat';
            app.CameraParameters2EditFieldLabel = uilabel(app.CamerasTab);
            app.CameraParameters2EditFieldLabel.Text = 'Camera Parameters 2';
            app.CameraParameters2EditField = uieditfield(app.CamerasTab, 'text');
            app.CameraParameters2EditField.Value = 'cameraParameters2.mat';
            app.CameraParameters3EditFieldLabel = uilabel(app.CamerasTab);
            app.CameraParameters3EditFieldLabel.Text = 'Camera Parameters 3';
            app.CameraParameters3EditField = uieditfield(app.CamerasTab, 'text');
            app.CameraParameters3EditField.Value = 'cameraParameters3.mat';
            app.ResolutionofLogitechc270DropDownLabel = uilabel(app.CamerasTab);
            app.ResolutionofLogitechc270DropDownLabel.Text = 'Resolution of Logitech c270 ';
            app.ResolutionofLogitechc270DropDown = uidropdown(app.CamerasTab);
            app.ResolutionofLogitechc270DropDown.Items = {'RGB24_640x480', 'RGB24_1280x720'};
            app.ResolutionofLogitechc270DropDown.Value = 'RGB24_640x480';
            app.ResolutionKeyEditFieldLabel = uilabel(app.CamerasTab);
            app.ResolutionKeyEditFieldLabel.Text = 'Resolution Key';
            app.ResolutionKeyEditField = uieditfield(app.CamerasTab, 'text');
            app.ResolutionKeyEditField.Value = 'RGB24_640x480';
            app.UseCustomCameraSwitchLabel = uilabel(app.CamerasTab);
            app.UseCustomCameraSwitchLabel.Text = 'Use Custom Camera';
            app.UseCustomCameraSwitch = uiswitch(app.CamerasTab, 'slider');
            app.PoseEstimationTab = uitab(app.TabGroup);
            app.PoseEstimationTab.Title = 'Pose Estimation';
            app.MarkerDictionaryfirstentryistheoriginEditFieldLabel = uilabel(app.PoseEstimationTab);
            app.MarkerDictionaryfirstentryistheoriginEditFieldLabel.Text = 'Marker Dictionary (first entry is the origin)';
            app.MarkerDictionaryfirstentryistheoriginEditField = uieditfield(app.PoseEstimationTab, 'text');
            app.MarkerDictionaryfirstentryistheoriginEditField.Value = '[1]';
            app.MarkerSizemEditFieldLabel = uilabel(app.PoseEstimationTab);
            app.MarkerSizemEditFieldLabel.Text = 'Marker Size [m]';
            app.MarkerSizemEditField = uieditfield(app.PoseEstimationTab, 'text');
            app.MarkerSizemEditField.Value = '0.05';
            app.UsePoseEstimationSwitchLabel = uilabel(app.PoseEstimationTab);
            app.UsePoseEstimationSwitchLabel.Text = 'Use Pose Estimation';
            app.UsePoseEstimationSwitch = uiswitch(app.PoseEstimationTab, 'slider');
            app.UsePoseEstimationSwitch.Value = 'On';
            app.ProbabilityThresholdforMarkerMatchingEditFieldLabel = uilabel(app.PoseEstimationTab);
            app.ProbabilityThresholdforMarkerMatchingEditFieldLabel.Text = 'Probability Threshold for Marker Matching';
            app.ProbabilityThresholdforMarkerMatchingEditField = uieditfield(app.PoseEstimationTab, 'text');
            app.ProbabilityThresholdforMarkerMatchingEditField.Value = '0.9';
            app.ObjectDetectionTab = uitab(app.TabGroup);
            app.ObjectDetectionTab.Title = 'Object Detection';
            app.NumberofobjectinstanceswrtobjectIDsEditFieldLabel = uilabel(app.ObjectDetectionTab);
            app.NumberofobjectinstanceswrtobjectIDsEditFieldLabel.Text = 'Number of object instances wrt. object IDs ';
            app.NumberofobjectinstanceswrtobjectIDsEditField = uieditfield(app.ObjectDetectionTab, 'text');
            app.NumberofobjectinstanceswrtobjectIDsEditField.Value = '[1]';
            app.MinimumdistancebetweenobjectspxEditFieldLabel = uilabel(app.ObjectDetectionTab);
            app.MinimumdistancebetweenobjectspxEditFieldLabel.Text = 'Minimum distance between objects [px]';
            app.MinimumdistancebetweenobjectspxEditField = uieditfield(app.ObjectDetectionTab, 'text');
            app.MinimumdistancebetweenobjectspxEditField.Value = '30';
            app.MinimumobjectsizeforROIestimationmEditFieldLabel = uilabel(app.ObjectDetectionTab);
            app.MinimumobjectsizeforROIestimationmEditFieldLabel.Text = 'Minimum object size for ROI estimation [m]';
            app.MinimumobjectsizeforROIestimationmEditField = uieditfield(app.ObjectDetectionTab, 'text');
            app.MinimumobjectsizeforROIestimationmEditField.Value = '0.2';
            app.ObjectdictionaryEditFieldLabel = uilabel(app.ObjectDetectionTab);
            app.ObjectdictionaryEditFieldLabel.Text = 'Object dictionary';
            app.ObjectdictionaryEditField = uieditfield(app.ObjectDetectionTab, 'text');
            app.ObjectdictionaryEditField.Value = 'objectDict.mat';
            app.UseObjectTrackingSwitchLabel = uilabel(app.ObjectDetectionTab);
            app.UseObjectTrackingSwitchLabel.Text = 'Use Object Tracking';
            app.UseObjectTrackingSwitch = uiswitch(app.ObjectDetectionTab, 'slider');
            app.UseObjectTrackingSwitch.Value = 'On';
            app.ProbabilityThresholdforObjectMatchingEditFieldLabel = uilabel(app.ObjectDetectionTab);
            app.ProbabilityThresholdforObjectMatchingEditFieldLabel.Text = 'Probability Threshold for Object Matching';
            app.ProbabilityThresholdforObjectMatchingEditField = uieditfield(app.ObjectDetectionTab, 'text');
            app.ProbabilityThresholdforObjectMatchingEditField.Value = '0.9';
            app.FixedVarianceforMatchingmEditFieldLabel = uilabel(app.ObjectDetectionTab);
            app.FixedVarianceforMatchingmEditFieldLabel.Text = 'Fixed Variance for Matching [m]';
            app.FixedVarianceforMatchingmEditField = uieditfield(app.ObjectDetectionTab, 'text');
            app.FixedVarianceforMatchingmEditField.Value = '0.1';
            app.FeatureQualityEditFieldLabel = uilabel(app.ObjectDetectionTab);
            app.FeatureQualityEditFieldLabel.Text = 'Feature Quality';
            app.FeatureQualityEditField = uieditfield(app.ObjectDetectionTab, 'text');
            app.FeatureQualityEditField.Value = '200';
            app.NumberofFeatureOctavesEditFieldLabel = uilabel(app.ObjectDetectionTab);
            app.NumberofFeatureOctavesEditFieldLabel.Text = 'Number of Feature Octaves';
            app.NumberofFeatureOctavesEditField = uieditfield(app.ObjectDetectionTab, 'text');
            app.NumberofFeatureOctavesEditField.Value = '3';
            app.RatioforambiguousMatchesEditFieldLabel = uilabel(app.ObjectDetectionTab);
            app.RatioforambiguousMatchesEditFieldLabel.Text = 'Ratio for ambiguous Matches';
            app.RatioforambiguousMatchesEditField = uieditfield(app.ObjectDetectionTab, 'text');
            app.RatioforambiguousMatchesEditField.Value = '0.6';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.Processvarianceforlinearmotionofcamerams2EditFieldLabel = uilabel(app.FilterTab);
            app.Processvarianceforlinearmotionofcamerams2EditFieldLabel.Text = 'Process variance for linear motion of camera [m/s**2]';
            app.Processvarianceforlinearmotionofcamerams2EditField = uieditfield(app.FilterTab, 'text');
            app.Processvarianceforlinearmotionofcamerams2EditField.Value = '1.';
            app.Processvarianceforangularmotionofcamerarads2EditFieldLabel = uilabel(app.FilterTab);
            app.Processvarianceforangularmotionofcamerarads2EditFieldLabel.Text = 'Process variance for angular motion of camera  [rad/s**2]';
            app.Processvarianceforangularmotionofcamerarads2EditField = uieditfield(app.FilterTab, 'text');
            app.Processvarianceforangularmotionofcamerarads2EditField.Value = '1.';
            app.Processvarianceforlinearmotionofobjectms2EditFieldLabel = uilabel(app.FilterTab);
            app.Processvarianceforlinearmotionofobjectms2EditFieldLabel.Text = 'Process variance for linear motion of object [m/s**2]';
            app.Processvarianceforlinearmotionofobjectms2EditField = uieditfield(app.FilterTab, 'text');
            app.Processvarianceforlinearmotionofobjectms2EditField.Value = '1.';
            app.MeasurementvarianceforpositionofstereocamerasmEditFieldLabel = uilabel(app.FilterTab);
            app.MeasurementvarianceforpositionofstereocamerasmEditFieldLabel.Text = 'Measurement variance for position of stereo cameras [m]';
            app.MeasurementvarianceforpositionofstereocamerasmEditField = uieditfield(app.FilterTab, 'text');
            app.MeasurementvarianceforpositionofstereocamerasmEditField.Value = '0.1';
            app.MeasurementvariancefororientationofcameraradEditFieldLabel = uilabel(app.FilterTab);
            app.MeasurementvariancefororientationofcameraradEditFieldLabel.Text = 'Measurement variance for orientation of stereo cameras [rad]';
            app.MeasurementvariancefororientationofstereocamerasradEditField = uieditfield(app.FilterTab, 'text');
            app.MeasurementvariancefororientationofstereocamerasradEditField.Value = '0.1';
            app.MeasurementvarianceforpositionofobjectmEditFieldLabel = uilabel(app.FilterTab);
            app.MeasurementvarianceforpositionofobjectmEditFieldLabel.Text = 'Measurement variance for position of object [m]';
            app.MeasurementvarianceforpositionofobjectmEditField = uieditfield(app.FilterTab, 'text');
            app.MeasurementvarianceforpositionofobjectmEditField.Value = '0.01';
            app.MeasurementvarianceforpositionofcameramEditField_2Label = uilabel(app.FilterTab);
            app.MeasurementvarianceforpositionofcameramEditField_2Label.Text = 'Measurement variance for position of camera [m]';
            app.MeasurementvarianceforpositionofcameramEditField = uieditfield(app.FilterTab, 'text');
            app.MeasurementvarianceforpositionofcameramEditField.Value = '0.1';
            app.MeasurementvariancefororientationofcameraradEditField_2Label = uilabel(app.FilterTab);
            app.MeasurementvariancefororientationofcameraradEditField_2Label.Text = 'Measurement variance for orientation of camera [rad]';
            app.MeasurementvariancefororientationofcameraradEditField = uieditfield(app.FilterTab, 'text');
            app.MeasurementvariancefororientationofcameraradEditField.Value = '0.1';
            app.RunButton = uibutton(app.UIFigure, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.FreezeMapButton = uibutton(app.UIFigure, 'state');
            app.FreezeMapButton.ValueChangedFcn = createCallbackFcn(app, @FreezeMapButtonValueChanged, true);
            app.FreezeMapButton.Text = 'Freeze Map';
            app.FreezeStereoPoseButton = uibutton(app.UIFigure, 'state');
            app.FreezeStereoPoseButton.ValueChangedFcn = createCallbackFcn(app, @FreezeStereoPoseButtonValueChanged, true);
            app.FreezeStereoPoseButton.Text = 'Freeze Stereo Pose';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Run Fusion - Version 0 - 20/01/2020 -  Master Thesis ''A Pose Estimation and Object Tracking Framework for Behavior Studies using Camera Fusion'' - Bachmann David, Wolf Julian, Hess Stephan';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo.jpg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunFusion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end